classdef NUMINT_TRAPEZOIDRULE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CLEARButton                   matlab.ui.control.Button
        RESULTEditField               matlab.ui.control.EditField
        RESULTEditFieldLabel          matlab.ui.control.Label
        CalculateButton               matlab.ui.control.Button
        noofintervalsEditField        matlab.ui.control.EditField
        noofintervalsEditFieldLabel   matlab.ui.control.Label
        UpperBoundaryEditField        matlab.ui.control.EditField
        UpperBoundaryEditFieldLabel   matlab.ui.control.Label
        LowerBoundaryEditField        matlab.ui.control.EditField
        LowerBoundaryEditFieldLabel   matlab.ui.control.Label
        FunctionToIntegrateEditField  matlab.ui.control.EditField
        FunctionToIntegrateEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
        
        f = str2double(get(handles.f, 'string'));
        a = str2double(get(handles.a, 'string'));
        b = str2double(get(handles.b, 'string'));
        n = str2double(get(handles.n, 'string'));
            
        % Calculate width of each interval
        h = (b - a) / n;

        % Initialize sum
        sum = 0;

        % Evaluate function at each endpoint and each midpoint of interval
        for i = 0:n
             x = a + i*h;
             if i == 0 || i == n
                sum = sum + f(x);
            else
                sum = sum + 2*f(x);
            end
        end

        % Multiply sum by width of each interval and divide by 2 to get result
        result = h * sum / 2;
        set(handles.result, 'string', product)


        disp(['The approximate integral of f(x) from ' num2str(a) ' to ' num2str(b) ' using the trapezoidal rule with ' num2str(n) ' intervals is ' num2str(result)])

        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
             clear result f a b n h sum x;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FunctionToIntegrateEditFieldLabel = uilabel(app.UIFigure);
            app.FunctionToIntegrateEditFieldLabel.Text = 'FunctionToIntegrate';
            app.FunctionToIntegrateEditField = uieditfield(app.UIFigure, 'text');
            app.FunctionToIntegrateEditField.Tag = 'f';
            app.LowerBoundaryEditFieldLabel = uilabel(app.UIFigure);
            app.LowerBoundaryEditFieldLabel.Tag = 'a';
            app.LowerBoundaryEditFieldLabel.Text = 'Lower Boundary';
            app.LowerBoundaryEditField = uieditfield(app.UIFigure, 'text');
            app.LowerBoundaryEditField.Tag = 'a';
            app.UpperBoundaryEditFieldLabel = uilabel(app.UIFigure);
            app.UpperBoundaryEditFieldLabel.Tag = 'b';
            app.UpperBoundaryEditFieldLabel.Text = 'Upper Boundary';
            app.UpperBoundaryEditField = uieditfield(app.UIFigure, 'text');
            app.UpperBoundaryEditField.Tag = 'b';
            app.noofintervalsEditFieldLabel = uilabel(app.UIFigure);
            app.noofintervalsEditFieldLabel.Text = 'no of intervals';
            app.noofintervalsEditField = uieditfield(app.UIFigure, 'text');
            app.noofintervalsEditField.Tag = 'n';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.RESULTEditFieldLabel = uilabel(app.UIFigure);
            app.RESULTEditFieldLabel.Tag = 'Result';
            app.RESULTEditFieldLabel.Text = 'RESULT';
            app.RESULTEditField = uieditfield(app.UIFigure, 'text');
            app.RESULTEditField.Tag = 'result';
            app.CLEARButton = uibutton(app.UIFigure, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = 'CLEAR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NUMINT_TRAPEZOIDRULE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end